classdef metadata_query < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        MetadataWizardPanel          matlab.ui.container.Panel
        selectdirectoryButton        matlab.ui.control.Button
        SessionInfoLabel_2           matlab.ui.control.Label
        SelectDirectoryTextArea      matlab.ui.control.TextArea
        NextButton                   matlab.ui.control.Button
        MetadataWizardLabel          matlab.ui.control.Label
        MetadataWizardPanel_3        matlab.ui.container.Panel
        MetadataWizardPanel_2        matlab.ui.container.Panel
        ErrorMessageLabel            matlab.ui.control.Label
        StartTimeEditField           matlab.ui.control.EditField
        StartTimeEditFieldLabel      matlab.ui.control.Label
        IdentifierEditField          matlab.ui.control.EditField
        IdentifierEditFieldLabel     matlab.ui.control.Label
        SessionIDEditField           matlab.ui.control.EditField
        SessionIDEditFieldLabel      matlab.ui.control.Label
        InstitutionEditField         matlab.ui.control.EditField
        InstitutionEditFieldLabel    matlab.ui.control.Label
        FirstNameEditField           matlab.ui.control.EditField
        FirstNameEditFieldLabel      matlab.ui.control.Label
        LastNameEditField            matlab.ui.control.EditField
        LastNameEditFieldLabel       matlab.ui.control.Label
        BackButton_2                 matlab.ui.control.Button
        DescriptionTextArea          matlab.ui.control.TextArea
        DescriptionTextAreaLabel     matlab.ui.control.Label
        KeywordsTextArea             matlab.ui.control.TextArea
        KeywordsTextAreaLabel        matlab.ui.control.Label
        NextButton_2                 matlab.ui.control.Button
        SessionInfoLabel             matlab.ui.control.Label
        ExperimenterInfoLabel        matlab.ui.control.Label
        BackButton_3                 matlab.ui.control.Button
        SubjectSexDropDown           matlab.ui.control.DropDown
        SubjectSexDropDownLabel      matlab.ui.control.Label
        SubjectDOBDatePicker         matlab.ui.control.DatePicker
        SubjectDOBDatePickerLabel    matlab.ui.control.Label
        SubjectSpeciesDropDown       matlab.ui.control.DropDown
        SubjectSpeciesDropDownLabel  matlab.ui.control.Label
        SubmitButton                 matlab.ui.control.Button
        SubjectStrainTextArea        matlab.ui.control.TextArea
        SubjectStrainTextAreaLabel   matlab.ui.control.Label
        SubjectIDTextArea            matlab.ui.control.TextArea
        SubjectIDTextAreaLabel       matlab.ui.control.Label
        SubjectInfoLabel             matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BackButton_3
        function BackButton_3Pushed(app, event)
            app.MetadataWizardPanel_2.Visible = 'on';
            app.MetadataWizardPanel_3.Visible = 'off';
        end

        % Callback function: not associated with a component
        function BackButtonPushed(app, event)

        end

        % Button pushed function: NextButton_2
        function NextButton_2Pushed(app, event)
            startTime = app.StartTimeEditField.Value;

            error = false;
            if isempty(app.FirstNameEditField.Value)
                error = true;
                uialert(app.UIFigure, 'Please enter first name.', 'Missing Field', 'Icon', 'error');

            elseif isempty(app.LastNameEditField.Value)
                error = true;
                uialert(app.UIFigure, 'Please enter last name.', 'Missing Field', 'Icon', 'error');

            elseif isempty(app.InstitutionEditField.Value)
                error = true;
                uialert(app.UIFigure, 'Please enter institution name.', 'Missing Field', 'Icon', 'error');

            elseif isempty(app.SessionIDEditField.Value)
                error = true;
                uialert(app.UIFigure, 'Please enter the session ID.', 'Missing Field', 'Icon', 'error');

            elseif isempty(app.IdentifierEditField.Value)
                error = true;
                uialert(app.UIFigure, 'Please enter the indentifier.', 'Missing Field', 'Icon', 'error');

            elseif isempty(startTime)
                error = true;
                uialert(app.UIFigure, 'Please enter the start time.', 'Missing Field', 'Icon', 'error');

            elseif (strtrim(app.KeywordsTextArea.Value) == "")
                error = true;
                uialert(app.UIFigure, 'Please enter the keywords.', 'Missing Field', 'Icon', 'error');

            elseif (strtrim(app.DescriptionTextArea.Value) == "")
                error = true;
                uialert(app.UIFigure, 'Please enter the description.', 'Missing Field', 'Icon', 'error');                
            end

            try
               datetime(startTime,'Format','yyyy-MM-dd HH:mm:ss');
            catch
               error = true;
            end
            
            if ~error
                app.MetadataWizardPanel_2.Visible = 'off';
                app.MetadataWizardPanel_3.Visible = 'on'; 
            end
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            
        end

        % Callback function: not associated with a component
        function StartTimeTextAreaValueChanged(app, event)

        end

        % Callback function: SelectDirectoryTextArea, selectdirectoryButton
        function selectdirectoryButtonPushed(app, event)
            f = figure('Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            selectedDir = uigetdir;
            % Check if a directory was selected (uigetdir returns 0 if the user cancels the dialog)
            delete(f);
            if selectedDir ~= 0
                % Store the selected directory path in the Edit Field
                app.SelectDirectoryTextArea.Value = selectedDir;
            end
        end

        % Button pushed function: BackButton_2
        function BackButton_2Pushed(app, event)
            app.MetadataWizardPanel.Visible = 'on';
            app.MetadataWizardPanel_2.Visible = 'off';
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.MetadataWizardPanel.Visible = 'off';
            app.MetadataWizardPanel_2.Visible = 'on';
        end

        % Value changed function: StartTimeEditField
        function StartTimeEditFieldValueChanged(app, event)
            value = app.StartTimeEditField.Value;
            try
               datetime(value,'Format','yyyy-MM-dd HH:mm:ss');
               app.ErrorMessageLabel.Text = '';
            catch
               app.ErrorMessageLabel.Text = 'Invalid Datetime.';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MetadataWizardPanel_3 = uipanel(app.UIFigure);
            app.MetadataWizardPanel_3.Title = 'Metadata Wizard';
            app.SubjectInfoLabel = uilabel(app.MetadataWizardPanel_3);
            app.SubjectInfoLabel.Text = 'Subject Info';
            app.SubjectIDTextAreaLabel = uilabel(app.MetadataWizardPanel_3);
            app.SubjectIDTextAreaLabel.Text = 'Subject ID';
            app.SubjectIDTextArea = uitextarea(app.MetadataWizardPanel_3);
            app.SubjectStrainTextAreaLabel = uilabel(app.MetadataWizardPanel_3);
            app.SubjectStrainTextAreaLabel.Text = 'Subject Strain';
            app.SubjectStrainTextArea = uitextarea(app.MetadataWizardPanel_3);
            app.SubmitButton = uibutton(app.MetadataWizardPanel_3, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.SubjectSpeciesDropDownLabel = uilabel(app.MetadataWizardPanel_3);
            app.SubjectSpeciesDropDownLabel.Text = 'Subject Species';
            app.SubjectSpeciesDropDown = uidropdown(app.MetadataWizardPanel_3);
            app.SubjectSpeciesDropDown.Items = {'mus musculus (mouse)', 'homo sapien (human)'};
            app.SubjectSpeciesDropDown.Value = 'mus musculus (mouse)';
            app.SubjectDOBDatePickerLabel = uilabel(app.MetadataWizardPanel_3);
            app.SubjectDOBDatePickerLabel.Text = 'Subject DOB';
            app.SubjectDOBDatePicker = uidatepicker(app.MetadataWizardPanel_3);
            app.SubjectDOBDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.SubjectSexDropDownLabel = uilabel(app.MetadataWizardPanel_3);
            app.SubjectSexDropDownLabel.Text = 'Subject Sex';
            app.SubjectSexDropDown = uidropdown(app.MetadataWizardPanel_3);
            app.SubjectSexDropDown.Items = {'Male', 'Female', 'Unkonwn'};
            app.SubjectSexDropDown.Value = 'Male';
            app.BackButton_3 = uibutton(app.MetadataWizardPanel_3, 'push');
            app.BackButton_3.ButtonPushedFcn = createCallbackFcn(app, @BackButton_3Pushed, true);
            app.BackButton_3.Text = 'Back';
            app.MetadataWizardPanel_2 = uipanel(app.MetadataWizardPanel_3);
            app.MetadataWizardPanel_2.Title = 'Metadata Wizard';
            app.ExperimenterInfoLabel = uilabel(app.MetadataWizardPanel_2);
            app.ExperimenterInfoLabel.Text = 'Experimenter Info';
            app.SessionInfoLabel = uilabel(app.MetadataWizardPanel_2);
            app.SessionInfoLabel.Text = 'Session Info';
            app.NextButton_2 = uibutton(app.MetadataWizardPanel_2, 'push');
            app.NextButton_2.ButtonPushedFcn = createCallbackFcn(app, @NextButton_2Pushed, true);
            app.NextButton_2.Text = 'Next (3/3)';
            app.KeywordsTextAreaLabel = uilabel(app.MetadataWizardPanel_2);
            app.KeywordsTextAreaLabel.Text = 'Keywords';
            app.KeywordsTextArea = uitextarea(app.MetadataWizardPanel_2);
            app.DescriptionTextAreaLabel = uilabel(app.MetadataWizardPanel_2);
            app.DescriptionTextAreaLabel.Text = 'Description';
            app.DescriptionTextArea = uitextarea(app.MetadataWizardPanel_2);
            app.BackButton_2 = uibutton(app.MetadataWizardPanel_2, 'push');
            app.BackButton_2.ButtonPushedFcn = createCallbackFcn(app, @BackButton_2Pushed, true);
            app.BackButton_2.Text = 'Back (1/3)';
            app.LastNameEditFieldLabel = uilabel(app.MetadataWizardPanel_2);
            app.LastNameEditFieldLabel.Text = 'Last Name';
            app.LastNameEditField = uieditfield(app.MetadataWizardPanel_2, 'text');
            app.FirstNameEditFieldLabel = uilabel(app.MetadataWizardPanel_2);
            app.FirstNameEditFieldLabel.Text = 'First Name';
            app.FirstNameEditField = uieditfield(app.MetadataWizardPanel_2, 'text');
            app.InstitutionEditFieldLabel = uilabel(app.MetadataWizardPanel_2);
            app.InstitutionEditFieldLabel.Text = 'Institution';
            app.InstitutionEditField = uieditfield(app.MetadataWizardPanel_2, 'text');
            app.InstitutionEditField.Value = 'University of Rochester';
            app.SessionIDEditFieldLabel = uilabel(app.MetadataWizardPanel_2);
            app.SessionIDEditFieldLabel.Text = 'Session ID';
            app.SessionIDEditField = uieditfield(app.MetadataWizardPanel_2, 'text');
            app.IdentifierEditFieldLabel = uilabel(app.MetadataWizardPanel_2);
            app.IdentifierEditFieldLabel.Text = 'Identifier';
            app.IdentifierEditField = uieditfield(app.MetadataWizardPanel_2, 'text');
            app.StartTimeEditFieldLabel = uilabel(app.MetadataWizardPanel_2);
            app.StartTimeEditFieldLabel.Text = 'Start Time';
            app.StartTimeEditField = uieditfield(app.MetadataWizardPanel_2, 'text');
            app.StartTimeEditField.ValueChangedFcn = createCallbackFcn(app, @StartTimeEditFieldValueChanged, true);
            app.StartTimeEditField.Placeholder = '(yyyy-MM-dd HH:mm:ss)';
            app.ErrorMessageLabel = uilabel(app.MetadataWizardPanel_2);
            app.ErrorMessageLabel.Text = '';
            app.MetadataWizardPanel = uipanel(app.UIFigure);
            app.MetadataWizardPanel.Title = 'Metadata Wizard';
            app.MetadataWizardLabel = uilabel(app.MetadataWizardPanel);
            app.MetadataWizardLabel.Text = 'Metadata Wizard';
            app.NextButton = uibutton(app.MetadataWizardPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next (2/3)';
            app.SelectDirectoryTextArea = uitextarea(app.MetadataWizardPanel);
            app.SelectDirectoryTextArea.ValueChangedFcn = createCallbackFcn(app, @selectdirectoryButtonPushed, true);
            app.SelectDirectoryTextArea.Editable = 'off';
            app.SessionInfoLabel_2 = uilabel(app.MetadataWizardPanel);
            app.SessionInfoLabel_2.Text = {'Welcome to Metadata Wizard! This tool guides you to enter the experiment session info according the the NWB specifications. '; ''; 'First, please select the directory to write your metadata.'};
            app.selectdirectoryButton = uibutton(app.MetadataWizardPanel, 'push');
            app.selectdirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @selectdirectoryButtonPushed, true);
            app.selectdirectoryButton.Text = 'select directory';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = metadata_query

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end