classdef metadata_wizard < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        BrainFlowZZZMetadataWizardpage13Panel  matlab.ui.container.Panel
        SessionInfoLabel_2           matlab.ui.control.Label
        MetadataWizardLabel          matlab.ui.control.Label
        SelectDirectoryTextArea      matlab.ui.control.TextArea
        NextButton                   matlab.ui.control.Button
        selectdirectoryButton        matlab.ui.control.Button
        BrainFlowZZZMetadataWizardpage23Panel  matlab.ui.container.Panel
        FirstNameEditFieldLabel      matlab.ui.control.Label
        SessionIDEditFieldLabel      matlab.ui.control.Label
        IdentifierEditFieldLabel     matlab.ui.control.Label
        StartTimeEditFieldLabel      matlab.ui.control.Label
        KeywordsTextAreaLabel        matlab.ui.control.Label
        DescriptionTextAreaLabel     matlab.ui.control.Label
        EditField                    matlab.ui.control.EditField
        ErrorMessageLabel            matlab.ui.control.Label
        InstitutionEditFieldLabel    matlab.ui.control.Label
        LastNameEditFieldLabel       matlab.ui.control.Label
        SessionInfoLabel             matlab.ui.control.Label
        ExperimenterInfoLabel        matlab.ui.control.Label
        BackButton_2                 matlab.ui.control.Button
        NextButton_2                 matlab.ui.control.Button
        DescriptionTextArea          matlab.ui.control.TextArea
        KeywordsTextArea             matlab.ui.control.TextArea
        StartTimeEditField           matlab.ui.control.EditField
        IdentifierEditField          matlab.ui.control.EditField
        SessionIDEditField           matlab.ui.control.EditField
        InstitutionEditField         matlab.ui.control.EditField
        LastNameEditField            matlab.ui.control.EditField
        FirstNameEditField           matlab.ui.control.EditField
        BrainFlowZZZMetadataWizardpage33Panel  matlab.ui.container.Panel
        subjectinfoautofilledLabel   matlab.ui.control.Label
        SubjectIDEditFieldLabel      matlab.ui.control.Label
        SubjectSpeciesDropDownLabel  matlab.ui.control.Label
        SubjectDOBDatePickerLabel    matlab.ui.control.Label
        SubjectSexDropDownLabel      matlab.ui.control.Label
        SubjectStrainEditFieldLabel  matlab.ui.control.Label
        currentDirTextArea           matlab.ui.control.TextArea
        currentDirTextAreaLabel      matlab.ui.control.Label
        SubjectInfoLabel             matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        BackButton_3                 matlab.ui.control.Button
        SubmitButton                 matlab.ui.control.Button
        SubjectStrainEditField       matlab.ui.control.EditField
        SubjectSexDropDown           matlab.ui.control.DropDown
        SubjectDOBDatePicker         matlab.ui.control.DatePicker
        SubjectSpeciesDropDown       matlab.ui.control.DropDown
        SubjectIDEditField           matlab.ui.control.EditField
    end

    
    properties (Access = private)
        % Get the full path of the current script
        currentDir
        subjectFile
        subjectExists = false;
        subjectInfo = struct;
    end

    methods (Access = private)
        
        function [] = read_subjectInfo(app)
            app.currentDir = fileparts(mfilename('fullpath'));
            app.subjectFile = char(fullfile(app.currentDir, "subject_info.json"));
            app.currentDirTextArea.Value = app.subjectFile;
        end

        function [] = update_subjectInfo(app, subjectID)
            app.subjectInfo.(subjectID) = struct;
            app.subjectInfo.(subjectID).subjectStrain = app.SubjectStrainEditField.Value;
            app.subjectInfo.(subjectID).subjectSex = app.SubjectSexDropDown.Value;
            app.subjectInfo.(subjectID).subjectDOB = app.SubjectDOBDatePicker.Value;
            app.subjectInfo.(subjectID).subjectSpecies = app.SubjectSpeciesDropDown.Value;

            jsonString = jsonencode(app.subjectInfo);
            fileID = fopen(app.subjectFile, 'w');           
            fprintf(fileID, jsonString);
            fclose(fileID);
        end

        function [] = write_json(app)
            metadata = struct;
            metadata.firstName = app.FirstNameEditField.Value;
            metadata.lastName = app.LastNameEditField.Value;
            metadata.institution = app.InstitutionEditField.Value;
            metadata.sessionID = app.SessionIDEditField.Value;
            metadata.identifier = app.IdentifierEditField.Value;
            metadata.startTime = app.StartTimeEditField.Value;
            metadata.keywords = char(app.KeywordsTextArea.Value);
            metadata.description = char(app.DescriptionTextArea.Value);
            metadata.subjectID = app.SubjectIDEditField.Value;
            metadata.subjectSpecies = char(app.SubjectSpeciesDropDown.Value);
            metadata.subjectDOB = app.SubjectDOBDatePicker.Value;
            metadata.subjectSex = app.SubjectSexDropDown.Value;
            metadata.subjectStrain = app.SubjectStrainEditField.Value;

            % Step 2: Convert the MATLAB structure to a JSON formatted string
            jsonString = jsonencode(metadata);
            % Step 3: Open a file for writing
            filename = fullfile(char(app.SelectDirectoryTextArea.Value), 'metadata.json');
            if isfile(filename)
                fileattrib(filename, '+w', 'a'); % Remove write permission for all users
            end
            %app.Label.Text = app.SelectDirectoryTextArea.Value;
            fileID = fopen(filename, 'w');
                            
            % Step 4: Write the JSON string to the file
            fprintf(fileID, jsonString);
            
            % Step 5: Close the file
            fclose(fileID);
            fileattrib(filename, '-w', 'a'); % Remove write permission for all users
        end
        
        function [] = reset_fields(app)
            app.SelectDirectoryTextArea.Value = '';
            app.FirstNameEditField.Value = '';
            app.LastNameEditField.Value = '';
            app.InstitutionEditField.Value = 'University of Rochester';
            app.SessionIDEditField.Value = '';
            app.IdentifierEditField.Value = '';
            app.StartTimeEditField.Value = '';
            app.KeywordsTextArea.Value = '';
            app.DescriptionTextArea.Value = '';
            app.SubjectIDEditField.Value = '';
            app.SubjectSpeciesDropDown.Value = 'please select';
            app.SubjectDOBDatePicker.Value = NaT;
            app.SubjectSexDropDown.Value = 'please select';
            app.SubjectStrainEditField.Value = '';
            app.subjectinfoautofilledLabel.Visible = false;
        end
        
        function [] = read_existing_json(app)
            jsonFile = fullfile(char(app.SelectDirectoryTextArea.Value), 'metadata.json');
            fileContent = fileread(jsonFile);

            % Decode the JSON content
            metadata = jsondecode(fileContent);
            app.FirstNameEditField.Value = metadata.firstName;
            app.LastNameEditField.Value = metadata.lastName;
            app.InstitutionEditField.Value = metadata.institution;
            app.SessionIDEditField.Value = metadata.sessionID;
            app.IdentifierEditField.Value = metadata.identifier;
            app.StartTimeEditField.Value = metadata.startTime;
            app.KeywordsTextArea.Value = metadata.keywords;
            app.DescriptionTextArea.Value = metadata.description;
            app.SubjectIDEditField.Value = metadata.subjectID;
            app.SubjectSpeciesDropDown.Value = metadata.subjectSpecies;
            app.SubjectDOBDatePicker.Value = datetime(metadata.subjectDOB);
            app.SubjectSexDropDown.Value = metadata.subjectSex;
            app.SubjectStrainEditField.Value = metadata.subjectStrain;
        end
        
        function expData = read_experiment_xml(app)
            xmlFile = fullfile(char(app.SelectDirectoryTextArea.Value), 'Experiment.xml');
            s = xml2struct(xmlFile); % use function from file exchange 
            expData = struct;
            try
                expData.date = char(s.ThorImageExperiment.Date.Attributes.date);
            catch
                expData.date = '';
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BackButton_3
        function BackButton_3Pushed(app, event)
            app.BrainFlowZZZMetadataWizardpage23Panel.Visible = 'on';
            app.BrainFlowZZZMetadataWizardpage33Panel.Visible = 'off';
        end

        % Button pushed function: NextButton_2
        function NextButton_2Pushed(app, event)
            startTime = app.StartTimeEditField.Value;

            error = false;
            if isempty(app.FirstNameEditField.Value)
                error = true;
                uialert(app.UIFigure, 'Please enter first name.', 'Missing Field', 'Icon', 'error');

            elseif isempty(app.LastNameEditField.Value)
                error = true;
                uialert(app.UIFigure, 'Please enter last name.', 'Missing Field', 'Icon', 'error');

            elseif isempty(app.InstitutionEditField.Value)
                error = true;
                uialert(app.UIFigure, 'Please enter institution name.', 'Missing Field', 'Icon', 'error');

            elseif isempty(app.SessionIDEditField.Value)
                error = true;
                uialert(app.UIFigure, 'Please enter the session ID.', 'Missing Field', 'Icon', 'error');

            elseif isempty(app.IdentifierEditField.Value)
                error = true;
                uialert(app.UIFigure, 'Please enter the indentifier.', 'Missing Field', 'Icon', 'error');

            elseif isempty(startTime)
                error = true;
                uialert(app.UIFigure, 'Please enter the start time.', 'Missing Field', 'Icon', 'error');

            elseif (strtrim(app.KeywordsTextArea.Value) == "")
                error = true;
                uialert(app.UIFigure, 'Please enter the keywords.', 'Missing Field', 'Icon', 'error');

            elseif (strtrim(app.DescriptionTextArea.Value) == "")
                error = true;
                uialert(app.UIFigure, 'Please enter the description.', 'Missing Field', 'Icon', 'error');                
            end

            try
               datetime(startTime,'Format','yyyy-MM-dd HH:mm:ss');
            catch
               error = true;
            end
            
            if ~error
                app.BrainFlowZZZMetadataWizardpage23Panel.Visible = 'off';
                app.BrainFlowZZZMetadataWizardpage33Panel.Visible = 'on'; 
            end
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            error = false;
            if isempty(app.SubjectIDEditField.Value)
                error = true;
                uialert(app.UIFigure, 'Please enter subject ID.', 'Missing Field', 'Icon', 'error');

            elseif strcmp(app.SubjectSpeciesDropDown.Value, 'please select')
                error = true;
                uialert(app.UIFigure, 'Please select subject species.', 'Missing Field', 'Icon', 'error');
            
            elseif isempty(app.SubjectDOBDatePicker.Value)
                error = true;
                uialert(app.UIFigure, 'Please enter subject DOB.', 'Missing Field', 'Icon', 'error');
            
            elseif strcmp(app.SubjectSexDropDown.Value, 'please select')
                error = true;
                uialert(app.UIFigure, 'Please select subject sex.', 'Missing Field', 'Icon', 'error');

            elseif isempty(app.SubjectStrainEditField.Value)
                error = true;
                uialert(app.UIFigure, 'Please enter subject DOB.', 'Missing Field', 'Icon', 'error');
            end

            if ~error
                write_json(app)
                message = ['metadata written to ' char(app.SelectDirectoryTextArea.Value) '/' 'metadata.json'];
                title = 'Metadata Saved';
                icon = 'success';
                
                % replace hyphens with undersocres in the subjectID because
                % hypens are not allowed in matlab identifiers
                app.SubjectIDEditField.Value = replace(app.SubjectIDEditField.Value, '-', '_');
                update_subjectInfo(app, app.SubjectIDEditField.Value)
                uialert(app.UIFigure, message, title, 'Icon', icon, 'CloseFcn', @reset_app);
            end
            
            function reset_app(src, event)
                app.BrainFlowZZZMetadataWizardpage13Panel.Visible = 'on';
                app.BrainFlowZZZMetadataWizardpage23Panel.Visible = 'off';
                app.BrainFlowZZZMetadataWizardpage33Panel.Visible = 'off';
                reset_fields(app)
            end
        end

        % Callback function: SelectDirectoryTextArea, selectdirectoryButton
        function selectdirectoryButtonPushed(app, event)
            f = figure('Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            selectedDir = uigetdir;
            % Check if a directory was selected (uigetdir returns 0 if the user cancels the dialog)
            delete(f);
            if selectedDir ~= 0
                % Store the selected directory path in the Edit Field
                app.SelectDirectoryTextArea.Value = selectedDir;
                read_subjectInfo(app)
            end
        end

        % Button pushed function: BackButton_2
        function BackButton_2Pushed(app, event)
            app.BrainFlowZZZMetadataWizardpage13Panel.Visible = 'on';
            app.BrainFlowZZZMetadataWizardpage23Panel.Visible = 'off';
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if (app.SelectDirectoryTextArea.Value == "")
                uialert(app.UIFigure, 'Please select a directory.', 'Missing Field', 'Icon', 'error');
            return;
            end

            jsonFile = fullfile(char(app.SelectDirectoryTextArea.Value), 'metadata.json');
            if isfile(jsonFile)
                selection = uiconfirm(app.UIFigure, ...
                    'Metadata already exists in this folder. Do you wish to update it?', ...
                    'Confirmation', ...
                    'Options', {'Yes', 'No'}, ...
                    'DefaultOption', 2, ...
                    'CancelOption', 2);
                if strcmp(selection, 'Yes')
                    read_existing_json(app)
                else
                    app.SelectDirectoryTextArea.Value = '';
                    return
                end
            
            else
                xmlFile = fullfile(char(app.SelectDirectoryTextArea.Value), 'Experiment.xml');
                if isfile(xmlFile)
                    xmlData = read_experiment_xml(app);
                    try
                        date = datetime(xmlData.date, 'InputFormat', 'MM/dd/yyyy HH:mm:ss');
                    catch 
                        date = datetime(xmlData.date);
                    end
                    app.IdentifierEditField.Value = string(date, 'yyyy-MM-dd HH:mm:ss'); % use the date as the Identifier
                    app.StartTimeEditField.Value = string(date, 'yyyy-MM-dd HH:mm:ss');
                %else
                %    app.IdentifierEditField.Value = '';
                end

            end
            app.BrainFlowZZZMetadataWizardpage13Panel.Visible = 'off';
            app.BrainFlowZZZMetadataWizardpage23Panel.Visible = 'on';
        end

        % Value changed function: StartTimeEditField
        function StartTimeEditFieldValueChanged(app, event)
            value = app.StartTimeEditField.Value;
            try
               datetime(value,'Format','yyyy-MM-dd HH:mm:ss');
               app.ErrorMessageLabel.Text = '';
            catch
               app.ErrorMessageLabel.Text = 'Invalid Datetime.';
            end
        end

        % Value changed function: SubjectIDEditField
        function SubjectIDEditFieldValueChanged(app, event)
            subjectID = app.SubjectIDEditField.Value;
            subjectID = replace(subjectID, '-', '_');
            if isfile(app.subjectFile)
                app.subjectExists = true;
                app.subjectInfo = jsondecode(fileread(app.subjectFile));
                if isfield(app.subjectInfo, subjectID)
                    app.subjectinfoautofilledLabel.Visible = true;
                    app.SubjectStrainEditField.Value = app.subjectInfo.(subjectID).subjectStrain;
                    app.SubjectSexDropDown.Value = app.subjectInfo.(subjectID).subjectSex;
                    app.SubjectDOBDatePicker.Value = datetime(app.subjectInfo.(subjectID).subjectDOB, 'InputFormat', 'yyyy-MM-dd');
                    app.SubjectSpeciesDropDown.Value = app.subjectInfo.(subjectID).subjectSpecies;
                end    
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BrainFlowZZZMetadataWizardpage33Panel = uipanel(app.UIFigure);
            app.BrainFlowZZZMetadataWizardpage33Panel.Title = 'BrainFlowZZZ Metadata Wizard (page 3/3)';
            app.SubjectIDEditField = uieditfield(app.BrainFlowZZZMetadataWizardpage33Panel, 'text');
            app.SubjectIDEditField.ValueChangedFcn = createCallbackFcn(app, @SubjectIDEditFieldValueChanged, true);
            app.SubjectIDEditField.Tooltip = {'must start with a letter, and can contain letters, digits, and underscores.'};
            app.SubjectSpeciesDropDown = uidropdown(app.BrainFlowZZZMetadataWizardpage33Panel);
            app.SubjectSpeciesDropDown.Items = {'please select', 'Mus musculus', 'Rattus', 'Homo sapien'};
            app.SubjectSpeciesDropDown.Value = 'please select';
            app.SubjectDOBDatePicker = uidatepicker(app.BrainFlowZZZMetadataWizardpage33Panel);
            app.SubjectDOBDatePicker.Limits = [datetime([2010 1 1]) datetime([2023 4 7])];
            app.SubjectDOBDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.SubjectSexDropDown = uidropdown(app.BrainFlowZZZMetadataWizardpage33Panel);
            app.SubjectSexDropDown.Items = {'please select', 'M', 'F', 'U', 'O'};
            app.SubjectSexDropDown.Placeholder = 'please select';
            app.SubjectSexDropDown.Value = 'please select';
            app.SubjectStrainEditField = uieditfield(app.BrainFlowZZZMetadataWizardpage33Panel, 'text');
            app.SubmitButton = uibutton(app.BrainFlowZZZMetadataWizardpage33Panel, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.BackButton_3 = uibutton(app.BrainFlowZZZMetadataWizardpage33Panel, 'push');
            app.BackButton_3.ButtonPushedFcn = createCallbackFcn(app, @BackButton_3Pushed, true);
            app.BackButton_3.Text = 'Back';
            app.Label = uilabel(app.BrainFlowZZZMetadataWizardpage33Panel);
            app.Label.Text = '';
            app.SubjectInfoLabel = uilabel(app.BrainFlowZZZMetadataWizardpage33Panel);
            app.SubjectInfoLabel.Text = 'Subject Info';
            app.currentDirTextAreaLabel = uilabel(app.BrainFlowZZZMetadataWizardpage33Panel);
            app.currentDirTextAreaLabel.Enable = 'off';
            app.currentDirTextAreaLabel.Text = 'currentDir';
            app.currentDirTextArea = uitextarea(app.BrainFlowZZZMetadataWizardpage33Panel);
            app.currentDirTextArea.Editable = 'off';
            app.currentDirTextArea.Enable = 'off';
            app.SubjectStrainEditFieldLabel = uilabel(app.BrainFlowZZZMetadataWizardpage33Panel);
            app.SubjectStrainEditFieldLabel.Text = 'Subject Strain';
            app.SubjectSexDropDownLabel = uilabel(app.BrainFlowZZZMetadataWizardpage33Panel);
            app.SubjectSexDropDownLabel.Text = 'Subject Sex';
            app.SubjectDOBDatePickerLabel = uilabel(app.BrainFlowZZZMetadataWizardpage33Panel);
            app.SubjectDOBDatePickerLabel.Text = 'Subject DOB';
            app.SubjectSpeciesDropDownLabel = uilabel(app.BrainFlowZZZMetadataWizardpage33Panel);
            app.SubjectSpeciesDropDownLabel.Text = 'Subject Species';
            app.SubjectIDEditFieldLabel = uilabel(app.BrainFlowZZZMetadataWizardpage33Panel);
            app.SubjectIDEditFieldLabel.Text = 'Subject ID';
            app.subjectinfoautofilledLabel = uilabel(app.BrainFlowZZZMetadataWizardpage33Panel);
            app.subjectinfoautofilledLabel.Visible = 'off';
            app.subjectinfoautofilledLabel.Text = 'subject info auto filled!';
            app.BrainFlowZZZMetadataWizardpage23Panel = uipanel(app.UIFigure);
            app.BrainFlowZZZMetadataWizardpage23Panel.Title = 'BrainFlowZZZ Metadata Wizard (page 2/3)';
            app.FirstNameEditField = uieditfield(app.BrainFlowZZZMetadataWizardpage23Panel, 'text');
            app.LastNameEditField = uieditfield(app.BrainFlowZZZMetadataWizardpage23Panel, 'text');
            app.InstitutionEditField = uieditfield(app.BrainFlowZZZMetadataWizardpage23Panel, 'text');
            app.InstitutionEditField.Value = 'University of Rochester';
            app.SessionIDEditField = uieditfield(app.BrainFlowZZZMetadataWizardpage23Panel, 'text');
            app.SessionIDEditField.Placeholder = 'eg., 20210511-m1';
            app.IdentifierEditField = uieditfield(app.BrainFlowZZZMetadataWizardpage23Panel, 'text');
            app.StartTimeEditField = uieditfield(app.BrainFlowZZZMetadataWizardpage23Panel, 'text');
            app.StartTimeEditField.ValueChangedFcn = createCallbackFcn(app, @StartTimeEditFieldValueChanged, true);
            app.StartTimeEditField.Placeholder = '(yyyy-MM-dd HH:mm:ss)';
            app.KeywordsTextArea = uitextarea(app.BrainFlowZZZMetadataWizardpage23Panel);
            app.KeywordsTextArea.Placeholder = 'eg., BPN Zstack, B-act Zstack';
            app.DescriptionTextArea = uitextarea(app.BrainFlowZZZMetadataWizardpage23Panel);
            app.NextButton_2 = uibutton(app.BrainFlowZZZMetadataWizardpage23Panel, 'push');
            app.NextButton_2.ButtonPushedFcn = createCallbackFcn(app, @NextButton_2Pushed, true);
            app.NextButton_2.Text = 'Next';
            app.BackButton_2 = uibutton(app.BrainFlowZZZMetadataWizardpage23Panel, 'push');
            app.BackButton_2.ButtonPushedFcn = createCallbackFcn(app, @BackButton_2Pushed, true);
            app.BackButton_2.Text = 'Back';
            app.ExperimenterInfoLabel = uilabel(app.BrainFlowZZZMetadataWizardpage23Panel);
            app.ExperimenterInfoLabel.Text = 'Experimenter Info';
            app.SessionInfoLabel = uilabel(app.BrainFlowZZZMetadataWizardpage23Panel);
            app.SessionInfoLabel.Text = 'Session Info';
            app.LastNameEditFieldLabel = uilabel(app.BrainFlowZZZMetadataWizardpage23Panel);
            app.LastNameEditFieldLabel.Text = 'Last Name';
            app.InstitutionEditFieldLabel = uilabel(app.BrainFlowZZZMetadataWizardpage23Panel);
            app.InstitutionEditFieldLabel.Text = 'Institution';
            app.ErrorMessageLabel = uilabel(app.BrainFlowZZZMetadataWizardpage23Panel);
            app.ErrorMessageLabel.Text = '';
            app.EditField = uieditfield(app.BrainFlowZZZMetadataWizardpage23Panel, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Enable = 'off';
            app.EditField.Visible = 'off';
            app.DescriptionTextAreaLabel = uilabel(app.BrainFlowZZZMetadataWizardpage23Panel);
            app.DescriptionTextAreaLabel.Text = 'Description';
            app.KeywordsTextAreaLabel = uilabel(app.BrainFlowZZZMetadataWizardpage23Panel);
            app.KeywordsTextAreaLabel.Text = 'Keywords';
            app.StartTimeEditFieldLabel = uilabel(app.BrainFlowZZZMetadataWizardpage23Panel);
            app.StartTimeEditFieldLabel.Text = 'Start Time';
            app.IdentifierEditFieldLabel = uilabel(app.BrainFlowZZZMetadataWizardpage23Panel);
            app.IdentifierEditFieldLabel.Text = 'Identifier';
            app.SessionIDEditFieldLabel = uilabel(app.BrainFlowZZZMetadataWizardpage23Panel);
            app.SessionIDEditFieldLabel.Text = 'Session ID';
            app.FirstNameEditFieldLabel = uilabel(app.BrainFlowZZZMetadataWizardpage23Panel);
            app.FirstNameEditFieldLabel.Text = 'First Name';
            app.BrainFlowZZZMetadataWizardpage13Panel = uipanel(app.UIFigure);
            app.BrainFlowZZZMetadataWizardpage13Panel.Title = 'BrainFlowZZZ Metadata Wizard (page 1/3)';
            app.selectdirectoryButton = uibutton(app.BrainFlowZZZMetadataWizardpage13Panel, 'push');
            app.selectdirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @selectdirectoryButtonPushed, true);
            app.selectdirectoryButton.Text = 'select directory';
            app.NextButton = uibutton(app.BrainFlowZZZMetadataWizardpage13Panel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.SelectDirectoryTextArea = uitextarea(app.BrainFlowZZZMetadataWizardpage13Panel);
            app.SelectDirectoryTextArea.ValueChangedFcn = createCallbackFcn(app, @selectdirectoryButtonPushed, true);
            app.SelectDirectoryTextArea.Editable = 'off';
            app.MetadataWizardLabel = uilabel(app.BrainFlowZZZMetadataWizardpage13Panel);
            app.MetadataWizardLabel.Text = 'Metadata Wizard';
            app.SessionInfoLabel_2 = uilabel(app.BrainFlowZZZMetadataWizardpage13Panel);
            app.SessionInfoLabel_2.Text = {'Welcome to Metadata Wizard! This tool guides you to enter the experiment session info according the the NWB specifications. '; ''; 'First, please select the directory to write your metadata.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = metadata_wizard

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end